classdef CannonApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ProjectileAxes                  matlab.ui.control.UIAxes
        HorizontalDistancemSliderLabel  matlab.ui.control.Label
        HorizontalDistanceSlider        matlab.ui.control.Slider
        VerticalDistancemSliderLabel    matlab.ui.control.Label
        VerticalDistanceSlider          matlab.ui.control.Slider
        HorizontalDistanceText          matlab.ui.control.NumericEditField
        VerticalDistanceText            matlab.ui.control.NumericEditField
        AimButton                       matlab.ui.control.Button
        ResultsPanel                    matlab.ui.container.Panel
        AngleLabel                      matlab.ui.control.Label
        FlightTimesLabel                matlab.ui.control.Label
        AngleNumber                     matlab.ui.control.Label
        TimeNumber                      matlab.ui.control.Label
        ConnectButton                   matlab.ui.control.Button
        FireButton                      matlab.ui.control.Button
    end

    
    methods (Access = public)
        app.a;
        app.s;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: HorizontalDistanceSlider
        function HorizontalDistanceSliderValueChanged(app, event)
            value = app.HorizontalDistanceSlider.Value;
            app.HorizontalDistanceText.Value = value;
        end

        % Value changed function: HorizontalDistanceText
        function HorizontalDistanceTextValueChanged(app, event)
            value = app.HorizontalDistanceText.Value;
            app.HorizontalDistanceSlider.Value = value;
        end

        % Value changed function: VerticalDistanceSlider
        function VerticalDistanceSliderValueChanged(app, event)
            value = app.VerticalDistanceSlider.Value;
            app.VerticalDistanceText.Value = value;
        end

        % Value changed function: VerticalDistanceText
        function VerticalDistanceTextValueChanged(app, event)
            value = app.VerticalDistanceText.Value;
            app.VerticalDistanceSlider.Value = value;
        end

        % Button pushed function: AimButton
        function AimButtonPushed(app, event)
            theta = cannonFunction(app);
            if theta > 0
                servoTheta = 121 - theta; % Changes theta to correct input for servo
                writePosition(app.s, servoTheta);
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            clear app.a
            clear app.s
            app.a = arduino();
            app.s = servo(app.a, 'D3');
        end

        % Button pushed function: FireButton
        function FireButtonPushed(app, event)
            writeDigitalPin(app.a, 'D5', 1);
            pause(2);
            writeDigitalPin(app.a, 'D5', 0);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ProjectileAxes = uiaxes(app.UIFigure);
            title(app.ProjectileAxes, 'Title')
            xlabel(app.ProjectileAxes, 'X')
            ylabel(app.ProjectileAxes, 'Y')
            app.HorizontalDistancemSliderLabel = uilabel(app.UIFigure);
            app.HorizontalDistancemSliderLabel.Text = {'Horizontal Distance (m)'; ''};
            app.HorizontalDistanceSlider = uislider(app.UIFigure);
            app.HorizontalDistanceSlider.Limits = [0 6];
            app.HorizontalDistanceSlider.ValueChangedFcn = createCallbackFcn(app, @HorizontalDistanceSliderValueChanged, true);
            app.VerticalDistancemSliderLabel = uilabel(app.UIFigure);
            app.VerticalDistancemSliderLabel.Text = 'Vertical Distance (m)';
            app.VerticalDistanceSlider = uislider(app.UIFigure);
            app.VerticalDistanceSlider.Limits = [0 3];
            app.VerticalDistanceSlider.ValueChangedFcn = createCallbackFcn(app, @VerticalDistanceSliderValueChanged, true);
            app.HorizontalDistanceText = uieditfield(app.UIFigure, 'numeric');
            app.HorizontalDistanceText.Limits = [0 6];
            app.HorizontalDistanceText.ValueChangedFcn = createCallbackFcn(app, @HorizontalDistanceTextValueChanged, true);
            app.VerticalDistanceText = uieditfield(app.UIFigure, 'numeric');
            app.VerticalDistanceText.Limits = [0 3];
            app.VerticalDistanceText.ValueChangedFcn = createCallbackFcn(app, @VerticalDistanceTextValueChanged, true);
            app.AimButton = uibutton(app.UIFigure, 'push');
            app.AimButton.ButtonPushedFcn = createCallbackFcn(app, @AimButtonPushed, true);
            app.AimButton.Text = 'Aim!';
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.AngleLabel = uilabel(app.ResultsPanel);
            app.AngleLabel.Text = 'Angle (°):';
            app.FlightTimesLabel = uilabel(app.ResultsPanel);
            app.FlightTimesLabel.Text = 'Flight Time (s):';
            app.AngleNumber = uilabel(app.ResultsPanel);
            app.AngleNumber.Text = 'Angle';
            app.TimeNumber = uilabel(app.ResultsPanel);
            app.TimeNumber.Text = 'Time';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect!';
            app.FireButton = uibutton(app.UIFigure, 'push');
            app.FireButton.ButtonPushedFcn = createCallbackFcn(app, @FireButtonPushed, true);
            app.FireButton.Text = 'Fire!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CannonApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end